classdef AboutBox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AboutNumIntUIFigure  matlab.ui.Figure
        gpuStatus            matlab.ui.control.Label
        about                matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                gpuDevice;
                app.gpuStatus.Text = "Your GPU is supported!";
                app.gpuStatus.FontColor = '#77AC30';
            catch
                app.gpuStatus.Text = "Your GPU isn't supported by Matlab. NumInt will still run, but may be slower than expected.";
                app.gpuStatus.FontColor = '#A2142F';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AboutNumIntUIFigure = uifigure('Visible', 'off');
            app.AboutNumIntUIFigure.Name = 'About NumInt';
            app.about = uilabel(app.AboutNumIntUIFigure);
            app.about.Text = 'This is an about section. It can be as long or short as we want, and should be a forward facing message to the end user on what the program is and any other relevant information. Normally, you''d include an email here, but I honestly just don''t want to give freshman my email.';
            app.gpuStatus = uilabel(app.AboutNumIntUIFigure);
            app.gpuStatus.Text = '';
            % Show the figure after all components are created
            app.AboutNumIntUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AboutBox

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AboutNumIntUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AboutNumIntUIFigure)
        end
    end
end