classdef DrawTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        xLabel    matlab.ui.control.Label
        UIAxes    matlab.ui.control.UIAxes
    end

    properties (Access = private)
        drawActive = false;

        linePoint;
        linePlot;
        lineSet = zeros(2,1);

        increasing;

        shiftDown = false;
        ctrlDown = false;
        
        startXPos;
        startYPos;
    end

    methods (Access = private)
        
        function startLine(app, mousePos)
            % Keep bounds static
            xLimits = app.UIAxes.XLim;
            yLimits = app.UIAxes.YLim;

            app.startXPos = mousePos(1);
            app.startYPos = mousePos(2);

            % Plot mousePos
            app.linePoint = plot(app.UIAxes, mousePos(1), mousePos(2), 'o');

            debugReadout(app, mousePos);

            if app.lineSet(1, :) == [0 0]
                app.lineSet = mousePos;
            end

            if length(app.lineSet(:, 1)) == 2
                checkDir(app)
            end

            % Keep bounds static
            app.UIAxes.XLim = xLimits;
            app.UIAxes.YLim = yLimits;
            app.drawActive = true;
        end

        function debugReadout(app, mousePos)
            app.xLabel.Text = "x: " + num2str(mousePos(1)) + "  " + "y: " + num2str(mousePos(2));
        end

        function checkDir(app)
            if app.lineSet(2, 1) > app.lineSet(1,1)
                app.increasing = true;
            else 
                app.increasing = false;
            end
        end

        function endLine(app, mousePos)
            if (isscalar(app.lineSet(:, 1))) || (app.increasing && (mousePos(1) >= max(app.lineSet(:, 1)))) || (~app.increasing && (mousePos(1) <= min(app.lineSet(:, 1))))
                app.lineSet(end + 1, :) = mousePos;
            else
                warndlg('invalid pos')
                return
            end

            delete(app.linePoint);
            delete(app.linePlot);

            app.linePlot = plot(app.UIAxes, app.lineSet(:, 1), app.lineSet(:, 2));

            app.drawActive = false;
            startLine(app, mousePos);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            hold(app.UIAxes, "on");

            app.UIAxes.XLim = [-10 10];
            app.UIAxes.YLim = [-10 10];
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            mousePos = event.IntersectionPoint([1 2]);

            if ~(app.shiftDown || app.ctrlDown)
                mousePos = round(mousePos);
            end

            if ~app.drawActive
                startLine(app, mousePos);
            else
                endLine(app, mousePos);
            end
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            if isequal(event.Key, 'control')
                app.ctrlDown = true;
            elseif isequal(event.Key, 'shift')
                app.shiftDown = true;
            end
        end

        % Key release function: UIFigure
        function UIFigureKeyRelease(app, event)
            if isequal(event.Key, 'control')
                app.ctrlDown = false;
            elseif isequal(event.Key, 'shift')
                app.shiftDown = false;
            end
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            mousePos = event.IntersectionPoint([1 2]);

            if ~(app.ctrlDown || app.shiftDown)
                mousePos = round(mousePos);
            end
            
            if ~isnan(mousePos)
                app.xLabel.Position = [20, 20, 144, 40];
                app.xLabel.Text = "x: " + mousePos(1) + "   y: " + mousePos(2);
            else
                app.xLabel.Position = [-100, -100, 144, 40];
            end
            

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.xLabel = uilabel(app.UIFigure);
            app.xLabel.Text = 'x:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DrawTest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end